classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CloseApplicationButton      matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        GeneralTab                  matlab.ui.container.Tab
        StartButton                 matlab.ui.control.Button
        AbortButton                 matlab.ui.control.Button
        VideoCaptureInProgressLampLabel  matlab.ui.control.Label
        VideoCaptureInProgressLamp  matlab.ui.control.Lamp
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        DurationsEditFieldLabel     matlab.ui.control.Label
        DurationsEditField          matlab.ui.control.NumericEditField
        ParametersTab               matlab.ui.container.Tab
        UpdateParametersButton      matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
        VideoFormatDropDownLabel    matlab.ui.control.Label
        VideoFormatDropDown         matlab.ui.control.DropDown
    end


    properties (Access = private)
        Video = vid_config();
        vid;
        src;
        fid;
        abort = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VideoCaptureInProgressLamp.Enable = 'off';
            
            DefaultFormat = 'F7_Raw16_1024x768_Mode2';
            
            app.Video = vid_config('Grasshopper3', DefaultFormat);
            
            app.VideoFormatDropDown.Items = app.Video.SupportedFormats;            
            app.VideoFormatDropDown.Value = app.Video.SupportedFormats(contains(app.Video.SupportedFormats, DefaultFormat));
            
            % Setting default values on the UI from default/set values from the vid_config
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
            app.DurationsEditField.Value = 10;

            % XXX TODO The next several lines are copied exactly into the update parameters callback. Fix this.
            [app.vid, app.src] = flir_camera_open(app.Video);
            
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            zeroImage = zeros(app.Video.Height, app.Video.Width);
            switch app.Video.Depth
                case 8
                    hImage = imshow(uint8(zeroImage));
                case 16
                    hImage = imshow(uint16(zeroImage));
            end

            hImage.UserData{1} = false;
            hImage.UserData{2} = false;

%             axis image
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            disp('Ready to begin video capture')
            
        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            close all
            closereq
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            % XXX TODO Figure out to get rid of the Update parameters button.
            close all
            
            [app.vid, app.src] = flir_camera_open(app.Video);
            
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            zeroImage = zeros(app.Video.Height, app.Video.Width);
            switch app.Video.Depth
                case 8
                    hImage = imshow(uint8(zeroImage));
                case 16
                    hImage = imshow(uint16(zeroImage));
            end

            hImage.UserData{1} = false;
            hImage.UserData{2} = false;

%             axis image
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            disp('Video capture parameters have been successfully updated')
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Turning on the vid capture lamp
            app.VideoCaptureInProgressLamp.Enable = 'on';
            
            % Setting Frames Per Trigger
            Fps = 1000 / (app.Video.ExposureTime);
            NFrames = ceil(Fps * app.DurationsEditField.Value);
            triggerconfig(app.vid, 'manual');
            app.vid.FramesPerTrigger = NFrames;
                        
            % Opening the bin file
            imagetype = ['uint', num2str(app.Video.Depth)];
            
            filename = app.FileNameEditField.Value;
            filename = [filename, '_', num2str(app.Video.Width), 'x', ...
                           num2str(app.Video.Height), 'x', ...
                           num2str(NFrames), '_' imagetype];
            binfilename = [filename '.bin'];
            
            if ~isempty(dir(binfilename))
                msgbox('That file already exists. Change the filename and try again.');
                return
            end
            
            % Start Video
            start(app.vid)
     
            app.fid = fopen(binfilename, 'w');
            
            NFramesTaken = 0;
            NFramesAvailable = 0;
            cnt = 0;
            trigger(app.vid);
            
            disp('Beginning video capture...')
            
            % Taking the video
            while(NFramesTaken < app.vid.FramesPerTrigger)
       
%                 if app.vid.FramesAvailable==0
%                     disp('Looping through.');
%                     continue;
%                 end

                cnt = cnt + 1;
                
                NFramesAvailable(cnt,1) = app.vid.FramesAvailable;
                NFramesTaken = NFramesTaken + NFramesAvailable(cnt,1);
                %disp(['Num Grabbed Frames: ' num2str(NFramesAvailable(cnt,1)) '/' num2str(NFramesTaken)]);
            
                [data, ~, meta] = getdata(app.vid, NFramesAvailable(cnt,1));    
                    
                fwrite(app.fid, data, imagetype);
            
                if ~mod(cnt,5)
                    drawnow;
                end
                
                if app.abort == true
                    disp('Video capture aborted.')
                    app.abort = false;
                    break
                end
            
            end
            
            % Stops video collection
            app.VideoCaptureInProgressLamp.Enable = 'off';
            fclose(app.fid);
            clear app.fid
            stop(app.vid);
            
            disp('Video capture complete')

            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.abort = true;
        end

        % Value changed function: VideoFormatDropDown
        function VideoFormatDropDownValueChanged(app, event)
            app.Video.Format = app.VideoFormatDropDown.Value;
            FormatInfo = flir_extract_vidformat(app.Video.Format);            
            
            app.Video.ImageType = FormatInfo.ImageType;
            app.Video.Height = FormatInfo.Height;
            app.Video.Width = FormatInfo.Width;
            app.Video.Depth = FormatInfo.Depth;
            app.Video.Mode = FormatInfo.Mode;  
        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
            app.Video.ExposureTime = app.ExposureTimeEditField.Value;            
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            app.Video.Gamma = app.GammaEditField.Value;
        end

        % Value changed function: ShutterModeDropDown
        function ShutterModeDropDownValueChanged(app, event)
            app.Video.ExposureMode = app.ShutterModeDropDown.Value;            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.Video.Gain = app.GainEditField.Value;            
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            app.Video.Brightness = app.BrightnessEditField.Value;            
        end

        % Value changed function: FrameRateModeDropDown
        function FrameRateModeDropDownValueChanged(app, event)
            app.Video.FrameRateMode = app.FrameRateModeDropDown.Value;
        end

        % Value changed function: ExposureModeDropDown
        function ExposureModeDropDownValueChanged(app, event)
            app.Video.ExposureMode = app.ExposureModeDropDown.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CloseApplicationButton = uibutton(app.UIFigure, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.StartButton = uibutton(app.GeneralTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AbortButton = uibutton(app.GeneralTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.VideoCaptureInProgressLampLabel = uilabel(app.GeneralTab);
            app.VideoCaptureInProgressLampLabel.Text = 'Video Capture In Progress';
            app.VideoCaptureInProgressLamp = uilamp(app.GeneralTab);
            app.FileNameEditFieldLabel = uilabel(app.GeneralTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GeneralTab, 'text');
            app.DurationsEditFieldLabel = uilabel(app.GeneralTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UpdateParametersButton = uibutton(app.ParametersTab, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.ExposureModeDropDownLabel = uilabel(app.ParametersTab);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.ParametersTab);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExposureModeDropDownValueChanged, true);
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.ParametersTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.ParametersTab, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.ParametersTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ParametersTab, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.BrightnessEditFieldLabel = uilabel(app.ParametersTab);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.FrameRateModeDropDownLabel = uilabel(app.ParametersTab);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.ParametersTab);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.ValueChangedFcn = createCallbackFcn(app, @FrameRateModeDropDownValueChanged, true);
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.ParametersTab);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.ParametersTab);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShutterModeDropDownValueChanged, true);
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.ParametersTab);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.VideoFormatDropDownLabel = uilabel(app.ParametersTab);
            app.VideoFormatDropDownLabel.Text = 'Video Format';
            app.VideoFormatDropDown = uidropdown(app.ParametersTab);
            app.VideoFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoFormatDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end