classdef vid_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OKButton                    matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Video = ba_config_video('Grasshopper3');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % m = load([INSERT FILEPATH TO EXISTING PROPERTIES HERE!]);
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            m.ExposureMode = app.ExposureModeDropDown.Value;
            m.FrameRateMode = app.FrameRateModeDropDown.Value;
            m.ShutterMode = app.ShutterModeDropDown.Value;
            m.Gain = app.GammaEditField.Value;
            m.Gamma = app.GammaEditField.Value;
            m.Brightness = app.BrightnessEditField.Value;
            m.ExposureTime = app.ExposureTimeEditField.Value;
            save('video_test.mat', 'm');
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ExposureModeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.UIFigure);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateModeDropDownLabel = uilabel(app.UIFigure);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.UIFigure);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.UIFigure);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.UIFigure);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vid_parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end